classdef appPredictions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MusicClassificactionAppUIFigure  matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        LOADTab                      matlab.ui.container.Tab
        Button                       matlab.ui.control.Button
        PlaySongButton               matlab.ui.control.Button
        NameSongLabel                matlab.ui.control.Label
        LoadSongButton               matlab.ui.control.Button
        WaveformAxes                 matlab.ui.control.UIAxes
        FEATURESTab                  matlab.ui.container.Tab
        FeatureDescriptionArea       matlab.ui.control.TextArea
        Label                        matlab.ui.control.Label
        FEATURESSELECTIONLabel       matlab.ui.control.Label
        SPECTRALCENTROIDCheckBox     matlab.ui.control.CheckBox
        MELSPECTROGRAMCheckBox       matlab.ui.control.CheckBox
        HARMONICRATIOCheckBox        matlab.ui.control.CheckBox
        MFCCCheckBox                 matlab.ui.control.CheckBox
        PITCHCheckBox                matlab.ui.control.CheckBox
        FeatureAxes                  matlab.ui.control.UIAxes
        PREDICTIONTab                matlab.ui.container.Tab
        MetricsTextArea              matlab.ui.control.Label
        LoadedModelLabel             matlab.ui.control.Label
        PredictionResultLabel        matlab.ui.control.Label
        PredictButton                matlab.ui.control.Button
        LoadMODELButton              matlab.ui.control.Button
        ModelDropDown                matlab.ui.control.DropDown
        ModelDropDownLabel           matlab.ui.control.Label
        PredictionTypeDropDown       matlab.ui.control.DropDown
        PredictionTypeDropDownLabel  matlab.ui.control.Label
        FeaturesCombinationDropDown  matlab.ui.control.DropDown
        FeaturesCombinationDropDownLabel  matlab.ui.control.Label
        ConfusionMatrixAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        AudioFilePath % Ruta del archivo de audio
        AudioFileName % Nombre del archivo de audio
        isPlaying % Estado de reproducción (true = reproduciendo, false = detenido)
        audioData % Datos de audio cargados
        fs % Frecuencia de muestreo del audio
        extractedFeatures = struct(); %almacena los valores de cada característica
        featureFlags = struct(); %Flags para saber qué features ha activado el usuario
        trainedClassifier;
        ClassNames;
    end
    
    methods (Access = private)
        
        % Funcion para actualizar la gráfica
        function UpdateFeaturePlot(app, featureName)
            % Limpiar la gráfica anterior
            cla(app.FeatureAxes); % Limpiar el contenido del eje específico
            legend(app.FeatureAxes, 'off'); % Limpiar cualquier leyenda anterior
        
            % Asegurarse de que el eje está vacío antes de volver a graficar
            hold(app.FeatureAxes, 'off');

            switch featureName 
                case 'Pitch'
                   if isfield(app.extractedFeatures, 'Pitch') 
                       featureData = app.extractedFeatures.Pitch;
                
                        % Definir el intervalo de tiempo (0.5 segundos)
                        timeInterval = 0.5;
                        
                        % Obtener los tiempos disponibles
                        timeValues = featureData.Time;

                        % Encontrar los índices más cercanos a los
                        % múltiplos de 0.5 sin generar valures no enteros
                        sampleIndices = find(mod(timeValues, timeInterval) < (timeInterval / 2));
                        
                        % Filtrar los valores de tiempo y pitch
                        filteredTime = featureData.Time(sampleIndices);
                        filteredPitch = featureData.Values(sampleIndices);
                        
                        % Graficar los valores de Pitch a intervalos de 0.5 segundos
                        plot(app.FeatureAxes, filteredTime, filteredPitch, '-', 'LineWidth', 1.5);
                        xlabel(app.FeatureAxes, 'Time (s)');
                        ylabel(app.FeatureAxes, 'Pitch (Hz)');
                        title(app.FeatureAxes, 'Pitch Estimation');
                        ylim(app.FeatureAxes, [min(filteredPitch) - 20, max(filteredPitch) + 20]);
                        grid(app.FeatureAxes, 'on');
            
                        % Actualizar descripción
                        app.FeatureDescriptionArea.Value = [
                            "**Pitch (Altura Tonal)**"
                            ""
                            "El Pitch representa la altura tonal percibida de un sonido, basada en su frecuencia fundamental (f0)."
                            "Se estima en cada ventana temporal para seguir la evolución melódica."
                            ""
                            "Interpretación de la gráfica:"
                            "- Eje X: tiempo (segundos o frames)"
                            "- Eje Y: frecuencia de pitch (Hz)"
                            "Patrones suaves indican melodías legato; variaciones abruptas sugieren ritmos hablados o percusivos."
                        ];
                   else
                        app.FeatureDescriptionArea.Value = "No hay datos de Pitch disponibles para representar.";
                   end
                
                case 'MFCC'
                    if isfield(app.extractedFeatures, 'MFCC') && isfield(app.extractedFeatures.MFCC, 'Values')
                        featureData = app.extractedFeatures.MFCC.Values; % Obtener la matriz completa de MFCC
                
                        if isempty(featureData)
                            app.FeatureDescriptionArea.Value = "No hay datos de MFCC disponibles para representar.";
                            return;
                        end
                
                        % Representar los coeficientes MFCC como un mapa de
                        % color
                        imagesc(app.FeatureAxes, featureData'); 
                        
                        % Ajustar ejes
                        axis(app.FeatureAxes, 'tight'); % Ajustar automáticamente el eje
                        xlabel(app.FeatureAxes, 'Tiempo (frames)');
                        ylabel(app.FeatureAxes, 'Coeficientes MFCC');
                        title(app.FeatureAxes, 'Representación de MFCC');
                        grid(app.FeatureAxes, 'on');
                        
                        %Ajustar rango de colores
                        clim(app.FeatureAxes, [-1, 3]);
                        % Crear la barra de colores y ajustar los ticks
                        colorbarHandle = colorbar(app.FeatureAxes); % Obtener el handle de la barra de colores
                        colorbarHandle.Ticks = -1:0.15:3; % Ajustar las marcas de la barra de colores con un paso de 0.3
                        % Mostrar dimensiones en la consola para depuración
                        disp(['Dimensiones de los MFCC: ', num2str(size(featureData, 1)), ' x ', num2str(size(featureData, 2))]);
                        
                        % Actualizar descripción
                        app.FeatureDescriptionArea.Value =  ["**MFCC (Mel-Frequency Cepstral Coefficients)**"
                        ""
                        "Los MFCC capturan la envolvente espectral de la señal en bandas Mel, imitando la percepción humana."
                        "Se calculan aplicando FFT, banco de filtros Mel, logaritmo y DCT."
                        ""
                        "Interpretación de la gráfica:"
                        "- Eje X: tiempo (frames)"
                        "- Eje Y: número de coeficiente MFCC"
                        "Los colores indican energía. Patrones repetitivos revelan timbres o texturas características."];
                    else
                        app.FeatureDescriptionArea.Value = "No hay datos de MFCC disponibles para representar.";
                    end
                                    
                case 'HR'
                    if isfield(app.extractedFeatures, 'HR') && isfield(app.extractedFeatures.HR, 'Values')
                        featureData = app.extractedFeatures.HR.Values; % Obtener la matriz completa de HR
                        numSamples = length(featureData);

                        if isempty(featureData)
                            app.FeatureDescriptionArea.Value = "No hay datos de HR disponibles para representar.";
                            return;
                        end

                        % Generar vector de tiempo basado en la ventana y el solapamiento
                        frameDuration = 0.05; % 50ms en segundos (según la ventana usada), duración de cada frame
                        overlapDuration = 0.025; % 25ms de solapamiento

                        % Crear eje de tiempo
                        timeAxis = (0:numSamples-1) * (frameDuration - overlapDuration);


                        % Graficar los valores del HR
                        plot(app.FeatureAxes, timeAxis, featureData, 'LineWidth', 1.5);
                        xlabel(app.FeatureAxes, 'Tiempo (s)');
                        ylabel(app.FeatureAxes, 'Harmonic Ratio');
                        title(app.FeatureAxes, 'Harmonic Ratio');
                        grid(app.FeatureAxes, 'on');

                        % Actualizar descripción
                        app.FeatureDescriptionArea.Value = [ ...
                            "**Harmonic Ratio (Proporción Armónica)**"
                            ""
                            "Mide el grado de periodicidad: relación entre energía de la frecuencia fundamental"
                            "y energía total. Alto en sonidos tonales, bajo en ruido."
                            ""
                            "Interpretación de la gráfica:"
                            "- Eje X: tiempo (frames)"
                            "- Eje Y: valor de Harmonic Ratio (0–1)"
                            "Valores cercanos a 1 indican tonos puros; valores bajos, pasajes ruidosos o percusivos."
                                        ];

                        
                    else
                        app.FeatureDescriptionArea.Value = "No hay datos de HR disponibles para representar.";
                    end

                case 'MEL'
                    if isfield(app.extractedFeatures, 'MEL')
                        % Obtener los coeficientes Mel
                        melData = app.extractedFeatures.MEL.Values; % Dimensión [64 x N_frames]
                        
                        % Aplicar transformación logarítmica para mejorar visualización
                        melValuesLog = log10(melData + eps); % eps evita log(0)

                        % Representar el Mel Spectrogram en la app
                        imagesc(app.FeatureAxes, melValuesLog);  
                        axis(app.FeatureAxes, 'tight');
                        
                        % Etiquetas de los ejes
                        xlabel(app.FeatureAxes, 'Tiempo (frames)', 'FontSize', 12);
                        ylabel(app.FeatureAxes, 'Bandas seleccionadas', 'FontSize', 12);
                        title(app.FeatureAxes, 'Mel Spectrogram (Todas las bandas, escala log)', 'FontSize', 14);
                        
                        % Ajustar el rango de valores. Esto evita que los valores más pequeños sean invisibles y permite comparar mejor la energía.
                        clim(app.FeatureAxes, [log10(1e-8), log10(1e-4)]); 

                        % Configurar las etiquetas del eje Y (pero sin sobrecargar visualmente)
                        set(app.FeatureAxes, 'YTick', linspace(1, 64, 10), 'YTickLabel', round(linspace(62.5, 8000, 10))); 

                        % Añadir una barra de color
                        colorbar(app.FeatureAxes);
                        
                        % Aplicar colormap para mejorar contraste
                        colormap(app.FeatureAxes, 'hot'); 
                        
                        % Habilitar la cuadrícula
                        grid(app.FeatureAxes, 'on');
                    
                        % Actualizar descripción
                        app.FeatureDescriptionArea.Value = [ ...
                             "**Mel Spectrogram**"
                            ""
                            "Representación tiempo-frecuencia en escala Mel. Muestra energía en cada banda Mel a lo largo del tiempo."
                            ""
                            "Interpretación de la gráfica:"
                            "- Eje X: tiempo (segundos o frames)"
                            "- Eje Y: frecuencia en escala Mel"
                            "Colores brillantes indican alta energía. Un espectrograma denso sugiere sonido continuo; huecos, silencios o pausas."
                        ];
                    else
                        app.FeatureDescriptionArea.Value = "No hay datos de Mel Spectrogram disponibles para representar.";
                    end

                
                case 'Spectral'
                    if isfield(app.extractedFeatures, 'Spectral')
                        % Obtener los coeficientes Mel
                        spectralData = app.extractedFeatures.Spectral.Values;
                        frames = 1:length(spectralData);

                        % Graficar el Spectral Centroid
                        plot(app.FeatureAxes, frames, spectralData, 'LineWidth', 1.5);
                        axis(app.FeatureAxes, 'tight');
                        
                        % Etiquetas de los ejes
                        xlabel(app.FeatureAxes, 'Tiempo (frames)', 'FontSize', 12);
                        ylabel(app.FeatureAxes, 'Frecuencia (Hz)', 'FontSize', 12);
                        title(app.FeatureAxes, 'Spectral Centroid', 'FontSize', 14);
                                        
                        % Opcional: Usar escala logarítmica en Y si hay mucha variabilidad
                        set(app.FeatureAxes, 'YScale', 'log');  
                        
                        % Habilitar la cuadrícula
                        grid(app.FeatureAxes, 'on');
                    
                        % Actualizar descripción
                        app.FeatureDescriptionArea.Value = [ ...
                             "**Spectral Centroid (Centroide Espectral)**"
                            ""
                            "Centro de gravedad del espectro de magnitudes. Correlaciona con el brillo del sonido."
                            ""
                            "Interpretación de la gráfica:"
                            "- Eje X: tiempo (frames)"
                            "- Eje Y: frecuencia del centroide (Hz)"
                            "Picos altos señalan pasajes más brillantes/agudos; valores bajos, sonidos más graves o cálidos."
                        ];
                    else
                        app.FeatureDescriptionArea.Value = "No hay datos de Spectral Centroid disponibles para representar.";
                    end
                

            end
        end
           
        % ----Funcion para extraer el PITCH----
        function ExtractPitch(app)
            if isfield(app.extractedFeatures, 'Pitch') % Evitar recalcular si ya existe
                return;
            end
            
            % Tomamos solo los primeros 20 segundos del archivo de audio
            % porque se excede la memoria de sistema
            duration = 20; % duración en segundos
            app.audioData = app.audioData(1:min(duration * app.fs, length(app.audioData))); % Limitar los datos de audio

            % EXTRAER EL PITCH
            [pitchVals, locs] = pitch(app.audioData, app.fs, ...
            'Method', 'SRH', ...
            'WindowLength', round(0.05 * app.fs), ...
            'OverlapLength', round(0.045 * app.fs),...
            'SampleRate', app.fs, ...  % Asegurar que se utiliza la frecuencia de muestreo correcta
            'Range', [50 4000]);  % Ajustar el rango a las frecuencias de interés
            
            %Calcular promedio valores Pitch
            pitchMean = mean(pitchVals);

             % Guardar los valores y el promedio en la estructura
            app.extractedFeatures.Pitch = struct(...
                'Values', pitchVals, ... % Todos los valores de pitch
                'Time', locs / app.fs, ... % Tiempo en segundos
                'Mean', pitchMean); % Guardar el promedio del Pitch
   
            app.featureFlags.Pitch = true; % Marcar en el flag que se ha extraído
            
            % Mostrar en la consola (para depuración)
            disp('Pitch extraído y almacenado:');
            disp(['Pitch extraído. Promedio: ', num2str(pitchMean)]);

             % Si esta opción está activada, actualizar la gráfica
            if app.PITCHCheckBox.Value
                UpdateFeaturePlot(app, 'Pitch');
            end
        end

        % ----Funcion para extraer los MFCC----
        function ExtractMFCC(app)
            if isfield(app.extractedFeatures, 'MFCC') % Evitar recalcular si ya existe
                return;
            end

            % EXTRAER LOS MFCC
            coeffs = mfcc(app.audioData, app.fs, 'NumCoeffs', 14, 'WindowLength', round(0.025*app.fs), 'OverlapLength', round(0.015*app.fs));
            coeffs = coeffs(:, 2:end); % Excluir el primer coeficiente MFCC
            
             % Calcular la media de los coeficientes a lo largo del tiempo
            mfccMean = mean(coeffs, 1); 

             % Guardar en la estructura
            app.extractedFeatures.MFCC = struct();
            app.extractedFeatures.MFCC.Values = coeffs; % Guardar todos los coeficientes
            app.extractedFeatures.MFCC.coeffs = mfccMean;

            app.featureFlags.MFCC = true; % Marcar en el flag que se ha extraído
        
            % Mostrar en la consola (para depuración)
            disp('MFCC extraídos y almacenados:');
            disp(mfccMean);

             % Si esta opción está activada, actualizar la gráfica
            if app.MFCCCheckBox.Value
                UpdateFeaturePlot(app, 'MFCC');
            end
        end
        
        % ----Funcion para extraer el Harmonic Ratio----
        function ExtractHR(app)
            if isfield(app.extractedFeatures, 'HR') % Evitar recalcular si ya existe
                return;
            end

        %EXTRAER EL Harmonic Ratio
        hrValues = harmonicRatio(app.audioData, app.fs, ...
                   Window=hann(round(app.fs.*0.05),"periodic"), ...  % Ventana de 50 ms
                   OverlapLength=round(app.fs.*0.025));              % Superposición del 90%

        % GUARDAR los valores estadísticos en la estructura
        app.extractedFeatures.HR = struct();
        app.extractedFeatures.HR.Values = hrValues;
        app.extractedFeatures.HR.mean = mean(hrValues); % Promedio
        app.extractedFeatures.HR.std = std(hrValues);    % Desviación estándar
        app.extractedFeatures.HR.max = max(hrValues);    % Valor máximo
        app.extractedFeatures.HR.min = min(hrValues);    % Valor mínimo

        %PD. usar en este orden para la predicción: [HRmean, HRstd, HRmax, HRmin]

        % Mostrar en la consola (para depuración)
            disp('Valores estadísticos HR extraídos y almacenados:');
            disp(app.extractedFeatures.HR);

        % Si esta opción está activada, actualizar la gráfica
            if app.HARMONICRATIOCheckBox.Value
                UpdateFeaturePlot(app, 'HR');
            end
        end
        
        % ----Funcion para extraer Mel-Spectrogram----
        function ExtractMel(app)
            if isfield(app.extractedFeatures, 'MEL') % Evitar recalcular si ya existe
                return;
            end
            
            audioDataMono = mean(app.audioData,2); %simplificar analisis

            coeffs = melSpectrogram(audioDataMono,app.fs, ...
               'Window',hann(2048,'periodic'), ...
               'OverlapLength',1024, ...
               'FFTLength',4096, ...
               'NumBands',64, ...
               'FrequencyRange',[62.5,8e3]);

            % Guardar la matriz completa para la representación
            app.extractedFeatures.MEL.Values = coeffs;

            %Se extrae una matriz de coefficientes en 64 bandas de frecuencia
            %(64x605), por ello vamos a extraer coeficientes representativos del Mel Spectrogram 
            % distribuidos uniformemente entre las bandas de frecuencia

            % Seleccionar bandas representativas (las mismas usadas en el modelo)
            numCoeffs = 14;  
            selectedBands = round(linspace(1, 64, numCoeffs));  
            selectedFeatures = coeffs(selectedBands, :); % Extraer bandas seleccionadas
            featureMeans = mean(selectedFeatures, 2)';   % Promediar cada banda en el tiempo

            % Guardar los valores para la predicción
            app.extractedFeatures.MEL.melBands = featureMeans;
            
            % Mostrar en consola para verificar
            disp('Mel Spectrogram extraído:');
            disp(app.extractedFeatures.MEL);

             % Si el checkbox está activado, actualizar la gráfica
            if app.MELSPECTROGRAMCheckBox.Value
                UpdateFeaturePlot(app, 'MEL');
            end

        end
        
        % ----Funcion para extraer Espectral Centroid----
        function ExtractSpectral(app)
            if isfield(app.extractedFeatures, 'Spectral') % Evitar recalcular si ya existe
                return;
            end
            
            audioDataMono = mean(app.audioData,2); %simplificar analisis

            coeffs = spectralCentroid(audioDataMono, app.fs);
            
            %Obtenemos en total 2992 coeffs, para simplificarlos calculamos varias estadísticas 
            % (promedio, desviación estándar, mínimo y máximo) para representar mejor la variabilidad 
            % y la distribución de esta característica a lo largo del tiempo. 
            % Esto permite capturar más detalles sobre cómo cambia el contenido espectral del audio, 
            % lo que puede mejorar el rendimiento de los clasificadores al proporcionar información 
            % más rica.
            app.extractedFeatures.Spectral = struct();
            app.extractedFeatures.Spectral.Values = coeffs; % Guardar la matriz completa para la representación
            app.extractedFeatures.Spectral.mean = mean(coeffs); % Promedio
            app.extractedFeatures.Spectral.std = std(coeffs); % Desviación estándar
            app.extractedFeatures.Spectral.min = min(coeffs);
            app.extractedFeatures.Spectral.max = max(coeffs);

            %PD. usar en este orden para la predicción: [centroidMean, centroidStd, centroidMin, centroidMax]
             % Escalado opcional para visualización (normalización entre 0 y 1)
            app.extractedFeatures.Spectral.Normalized = (coeffs - min(coeffs)) / (max(coeffs) - min(coeffs));
           
            % Mostrar en la consola (para depuración)
            disp('Valores estadísticos Spectral Centroid extraídos y almacenados:');
            disp(app.extractedFeatures.Spectral);

            % Si esta opción está activada, actualizar la gráfica
            if app.SPECTRALCENTROIDCheckBox.Value
                UpdateFeaturePlot(app, 'Spectral');
            end

        end

        
        function validateFields(app, requiredFields)
            for i = 1:length(requiredFields)
                if ~isfield(app.extractedFeatures, requiredFields{i})
                    uialert(app.MusicClassificactionAppUIFigure, sprintf('Faltan características necesarias: %s', requiredFields{i}), 'Error');
                end
            end
        end
        
        function checkPredictReadiness(app)
            if ~isempty(app.PredictionTypeDropDown.Value) && ~isempty(app.FeaturesCombinationDropDown.Value)
                app.PredictButton.Visible = true;
                app.PredictionResultLabel.Visible = true;
            else
                app.PredictButton.Visible = false;
                app.PredictionResultLabel.Visible = false;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadSongButton
        function LoadSongButtonPushed(app, event)
            % Abre el explorador de archivos para seleccionar un archivo de audio
            [file, path] = uigetfile({'*.mp3;*.wav;*.au', 'Archivos de audio (*.mp3, *.wav, *.au)'}, 'Seleccionar archivo de audio');
            
            % Si se selecciona un archivo, actualiza la UI
            if file ~= 0
                % Guarda la ruta del archivo de audio
                app.AudioFilePath = fullfile(path, file);
                disp(['Ruta del archivo: ', app.AudioFilePath]);
                app.AudioFileName = file;  % Solo el nombre del archivo

                % Muestra la ruta del archivo en un área de texto o etiqueta
                app.NameSongLabel.Text = [app.AudioFileName];
            else
                % Si no se seleccionó ningún archivo, limpiar el label
                app.NameSongLabel.Text = '';
            end
            
            app.isPlaying = false;
        end

        % Button pushed function: PlaySongButton
        function PlaySongButtonPushed(app, event)
             % Verificar si se ha seleccionado un archivo
            if isempty(app.AudioFilePath)
                uialert(app.MusicClassificactionAppUIFigure, 'Please select an audio file.', 'Error', 'Icon', 'error');
                return;
            end
            
            % Si ya hay audio cargado, limpiarlo
            if ~isempty(app.audioData)
                app.audioData = [];
                cla(app.WaveformAxes); % Limpiar el gráfico de la onda
            end

            % Leer el archivo de audio
            [app.audioData, app.fs] = audioread(app.AudioFilePath);
            
            % Convertir a mono si es necesario
            if size(app.audioData, 2) > 1
                app.audioData = mean(app.audioData, 2);  % Convertir a mono promediando canales
            end

            % Limitar el audio a los primeros 30 segundos (si la canción es más larga)
            duration = min(30, length(app.audioData) / app.fs); % Asegurarse de no exceder la duración
            app.audioData = app.audioData(1:round(duration * app.fs)); % Extraer solo los primeros 30 segundos
            
            % Reproducir el archivo de audio solo si no está en reproducción
            if ~app.isPlaying
                sound(app.audioData, app.fs); % Reproducir el audio
                disp('Playing sound...');

                % Actualizar estado de reproducción
                app.isPlaying = true;
                
                % **Plot Waveform**
                plot(app.WaveformAxes, (1:length(app.audioData))/app.fs, app.audioData, ...
                    'Color', [0 0.447 0.741], 'LineWidth', 1.5);
                xlabel(app.WaveformAxes, 'Time (s)', 'FontSize', 12, 'FontWeight', 'bold');
                ylabel(app.WaveformAxes, 'Amplitude', 'FontSize', 12, 'FontWeight', 'bold');
                title(app.WaveformAxes, 'Waveform', 'FontSize', 14, 'FontWeight', 'bold');
                grid(app.WaveformAxes, 'on');
                xlim(app.WaveformAxes, [0 length(app.audioData)/app.fs]); % Ajuste de ejes
                ylim(app.WaveformAxes, [-1 1]); % Mantener dentro del rango normalizado
                axis(app.WaveformAxes, 'tight');

             end
        end

        % Button pushed function: Button
        function PauseSongButtonPushed(app, event)
            % Verificar si el usuario ha seleccionado un archivo
            if isempty(app.AudioFilePath)
                uialert(app.MusicClassificactionAppUIFigure, 'Por favor, selecciona un archivo de audio.', 'Error', 'Icon', 'error');
                return;
            end
            
            % Pausar el audio
            if app.isPlaying
                % Detener la reproducción del audio
                clear sound;
                
                % Actualizar el estado de reproducción
                app.isPlaying = false;
                
                disp('Audio pausado');
            end
        end

        % Value changed function: PITCHCheckBox
        function PitchCheckBoxValueChanged(app, event)
            if app.PITCHCheckBox.Value % si el check box está seleccionado se extrae y representa el pitch
                ExtractPitch(app);
                UpdateFeaturePlot(app, 'Pitch');
            else
                % Limpiar completamente la gráfica
                cla(app.FeatureAxes, 'reset'); 
                % Quitar la leyenda si existe
                legend(app.FeatureAxes, 'off'); 
                %Limpiar descripción   
                app.FeatureDescriptionArea.Value = "";                 
            end
        end

        % Value changed function: MFCCCheckBox
        function MFCCCheckBoxValueChanged(app, event)
            if app.MFCCCheckBox.Value % si el check box está seleccionado se extrae y representa los MFCC
                ExtractMFCC(app);
                UpdateFeaturePlot(app, 'MFCC');
            else
                % Limpiar completamente la gráfica
                cla(app.FeatureAxes, 'reset'); 
                % Quitar la leyenda si existe
                legend(app.FeatureAxes, 'off'); 
                %Limpiar descripción   
                app.FeatureDescriptionArea.Value = "";                
            end
            
        end

        % Value changed function: HARMONICRATIOCheckBox
        function HRCheckBoxValueChanged(app, event)
            if app.HARMONICRATIOCheckBox.Value % si el check box está seleccionado se extrae y representa el HR
                ExtractHR(app);
                UpdateFeaturePlot(app, 'HR');
            else
                % Limpiar completamente la gráfica
                cla(app.FeatureAxes, 'reset'); 
                % Quitar la leyenda si existe
                legend(app.FeatureAxes, 'off'); 
                %Limpiar descripción   
                app.FeatureDescriptionArea.Value = "";                
            end
            
        end

        % Value changed function: MELSPECTROGRAMCheckBox
        function MelCheckBoxValueChanged(app, event)
            if app.MELSPECTROGRAMCheckBox.Value % si el check box está seleccionado se extrae y representa los Mel Spectrogram
                ExtractMel(app);
                UpdateFeaturePlot(app, 'MEL');
            else
                % Limpiar completamente la gráfica
                cla(app.FeatureAxes, 'reset'); 
                % Quitar la leyenda si existe
                legend(app.FeatureAxes, 'off'); 
                %Limpiar descripción   
                app.FeatureDescriptionArea.Value = "";               
            end
            
        end

        % Value changed function: SPECTRALCENTROIDCheckBox
        function SpectralCheckBoxValueChanged(app, event)
            if app.SPECTRALCENTROIDCheckBox.Value % si el check box está seleccionado se extrae y representa el Spectral Centroid
                ExtractSpectral(app);
                UpdateFeaturePlot(app, 'Spectral');
            else
                % Limpiar completamente la gráfica
                cla(app.FeatureAxes, 'reset'); 
                % Quitar la leyenda si existe
                legend(app.FeatureAxes, 'off'); 
                %Limpiar descripción   
                app.FeatureDescriptionArea.Value = "";             
            end
            
        end

        % Button pushed function: LoadMODELButton
        function LoadModelButtonPushed(app, event)

            % Obtener las selecciones del usuario
            selectedPredictionType = app.PredictionTypeDropDown.Value;  % Genre o Emotion
            selectedFeatureCombination = app.FeaturesCombinationDropDown.Value;  % HR, HR_MFCC, etc.
            selectedModel = app.ModelDropDown.Value;  % KNN, NN, SVM
        
            % Definir la ruta de la carpeta y el nombre del archivo del modelo
            folderPath = fullfile('C:\Users\Ainho\OneDrive\Escritorio\TFG\Matlab\Tools\TFG_Ainhoa_AudioToolbox\Models', ...
                                  selectedPredictionType, selectedFeatureCombination);
            modelFilename = fullfile(folderPath, ['model_' selectedModel '.mat']);
        
            if exist(modelFilename, 'file')
                % Cargar el modelo guardado
                loadedModel = load(modelFilename);
                app.trainedClassifier = loadedModel.(sprintf('model_%s', selectedModel));
                disp(['Modelo cargado: ', modelFilename]);
        
                % Mostrar el modelo cargado en la app
                app.LoadedModelLabel.Text = ['Modelo cargado: ', selectedFeatureCombination, '_', selectedModel];
        
                % Cálculo de métricas de validación cruzada ===
                if isfield(app.trainedClassifier, 'ClassificationSVM') || ...
                   isfield(app.trainedClassifier, 'ClassificationKNN') || ...
                   isfield(app.trainedClassifier, 'ClassificationEnsemble') || ...
                   isfield(app.trainedClassifier, 'ClassificationTree') || ...
                   isfield(app.trainedClassifier, 'ClassificationNeuralNetwork')
                   
                    % Acceder al modelo interno
                    modelFields = fieldnames(app.trainedClassifier);
                    classifierField = modelFields{contains(modelFields, 'Classification')};
                    internalModel = app.trainedClassifier.(classifierField);
        
                    % Obtener predicciones mediante validación cruzada
                    cvModel = crossval(internalModel);
                    predictedLabels = kfoldPredict(cvModel);
                    trueLabels = internalModel.Y;
        
                    % Matriz de confusión
                    confMat = confusionmat(trueLabels, predictedLabels);
                    accuracy = sum(diag(confMat)) / sum(confMat(:));
        
                    % Cálculo de métricas por clase
                    numClasses = size(confMat, 1);
                    precision = zeros(numClasses,1);
                    recall = zeros(numClasses,1);
                    f1 = zeros(numClasses,1);
        
                    for i = 1:numClasses
                        TP = confMat(i,i);
                        FP = sum(confMat(:,i)) - TP;
                        FN = sum(confMat(i,:)) - TP;
                        precision(i) = TP / (TP + FP + eps);
                        recall(i) = TP / (TP + FN + eps);
                        f1(i) = 2 * (precision(i)*recall(i)) / (precision(i) + recall(i) + eps);
                    end
        
                    % Métricas promedio
                    avgPrecision = mean(precision);
                    avgRecall = mean(recall);
                    avgF1 = mean(f1);
        
                    % Mostrar métricas en el componente de la app
                    metricsText = sprintf(['Precision: %.2f\nRecall: %.2f\nF1-score: %.2f\nAccuracy: %.2f'], ...
                                           avgPrecision, avgRecall, avgF1, accuracy * 100);
                    app.MetricsTextArea.Text = metricsText;
                else
                    app.MetricsTextArea.Text = 'No se pudieron calcular métricas para este modelo.';
                end
        
                % Mostrar imagen de matriz de confusión
                if strcmpi(selectedPredictionType, 'Genre')
                    cmImageName = ['CM_genre_' selectedModel '.png'];
                elseif strcmpi(selectedPredictionType, 'Emotion')
                    cmImageName = ['CM_emotions_' selectedModel '.png'];
                else
                    cmImageName = '';
                end
        
                cmImagePath = fullfile(folderPath, cmImageName);
        
                if exist(cmImagePath, 'file')
                    imshow(cmImagePath, 'Parent', app.ConfusionMatrixAxes);
                    title(app.ConfusionMatrixAxes, 'Matriz de Confusión');
                else
                    cla(app.ConfusionMatrixAxes);
                    text(app.ConfusionMatrixAxes, 0.5, 0.5, 'Imagen de matriz de confusión no encontrada', ...
                        'HorizontalAlignment', 'center', 'FontSize', 10);
                end
        
            else
                uialert(app.MusicClassificactionAppUIFigure, ['El modelo seleccionado no existe: ', modelFilename], 'Error de Carga');
            end
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
     
             % Obtener la opción seleccionada del dropdown
            selectedFeatureCombination = app.FeaturesCombinationDropDown.Value;  % HR, HR_MFCC, etc..
            
            % Verificar si las características han sido extraídas previamente
            if isempty(app.extractedFeatures)
                uialert(app.MusicClassificactionAppUIFigure, 'Las características no han sido extraídas.', 'Error');
                return;
            end

            disp('Extracted Features: ')
            disp(app.extractedFeatures);
            % Asegúrate de que las características hayan sido extraídas previamente
            if ~isempty(app.extractedFeatures)
                % Extraer las características correctas según la combinación seleccionada
                switch selectedFeatureCombination
                    case 'HR'
                        requiredFields = {'HR'};
                        validateFields(app, requiredFields);

                        dataTable = table(app.extractedFeatures.HR.max, app.extractedFeatures.HR.mean, ...
                          app.extractedFeatures.HR.min, app.extractedFeatures.HR.std, ...
                          'VariableNames', {'HRmax', 'HRmean', 'HRmin', 'HRstd'});
                    case 'HR_MFCC'
                        requiredFields = {'HR', 'MFCC'};
                        validateFields(app, requiredFields);

                        dataTable = table(app.extractedFeatures.HR.max, app.extractedFeatures.HR.mean, ...
                          app.extractedFeatures.HR.min, app.extractedFeatures.HR.std, ...
                          app.extractedFeatures.MFCC.coeffs(9), app.extractedFeatures.MFCC.coeffs(10), ...
                          app.extractedFeatures.MFCC.coeffs(11), app.extractedFeatures.MFCC.coeffs(12), ...
                          app.extractedFeatures.MFCC.coeffs(13), app.extractedFeatures.MFCC.coeffs(1), ...
                          app.extractedFeatures.MFCC.coeffs(2), app.extractedFeatures.MFCC.coeffs(3), ...
                          app.extractedFeatures.MFCC.coeffs(4), app.extractedFeatures.MFCC.coeffs(5), ...
                          app.extractedFeatures.MFCC.coeffs(6), app.extractedFeatures.MFCC.coeffs(7), ...
                          app.extractedFeatures.MFCC.coeffs(8), ...
                          'VariableNames', {'HRmax', 'HRmean', 'HRmin', 'HRstd', ...
                                            'MFCC_10', 'MFCC_11', 'MFCC_12', 'MFCC_13', 'MFCC_14', ...
                                            'MFCC_2', 'MFCC_3', 'MFCC_4', 'MFCC_5', 'MFCC_6', ...
                                            'MFCC_7', 'MFCC_8', 'MFCC_9'});
                    case 'HR_MFCC_SpecC'
                        requiredFields = {'HR', 'MFCC', 'Spectral'};
                        validateFields(app, requiredFields);

                         dataTable = table(app.extractedFeatures.HR.max, app.extractedFeatures.HR.mean, ...
                          app.extractedFeatures.HR.min, app.extractedFeatures.HR.std, ...
                          app.extractedFeatures.MFCC.coeffs(9), app.extractedFeatures.MFCC.coeffs(10), ...
                          app.extractedFeatures.MFCC.coeffs(11), app.extractedFeatures.MFCC.coeffs(12), ...
                          app.extractedFeatures.MFCC.coeffs(13), app.extractedFeatures.MFCC.coeffs(1), ...
                          app.extractedFeatures.MFCC.coeffs(2), app.extractedFeatures.MFCC.coeffs(3), ...
                          app.extractedFeatures.MFCC.coeffs(4), app.extractedFeatures.MFCC.coeffs(5), ...
                          app.extractedFeatures.MFCC.coeffs(6), app.extractedFeatures.MFCC.coeffs(7), ...
                          app.extractedFeatures.MFCC.coeffs(8), ...
                          app.extractedFeatures.Spectral.max, app.extractedFeatures.Spectral.mean, ...
                          app.extractedFeatures.Spectral.min, app.extractedFeatures.Spectral.std, ...
                          'VariableNames', {'HRmax', 'HRmean', 'HRmin', 'HRstd', ...
                                            'MFCC_10', 'MFCC_11', 'MFCC_12', 'MFCC_13', 'MFCC_14', ...
                                            'MFCC_2', 'MFCC_3', 'MFCC_4', 'MFCC_5', 'MFCC_6', ...
                                            'MFCC_7', 'MFCC_8', 'MFCC_9', ...
                                            'centroidMax', 'centroidMean', 'centroidMin', 'centroidStd'});
                    case 'HR_MFCC_SpecC_MelSpec'
                        requiredFields = {'HR', 'MFCC', 'Spectral','MEL'};
                        validateFields(app, requiredFields);

                        dataTable = table(app.extractedFeatures.HR.max, app.extractedFeatures.HR.mean, ...
                          app.extractedFeatures.HR.min, app.extractedFeatures.HR.std, ...
                          app.extractedFeatures.MFCC.coeffs(9), app.extractedFeatures.MFCC.coeffs(10), ...
                          app.extractedFeatures.MFCC.coeffs(11), app.extractedFeatures.MFCC.coeffs(12), ...
                          app.extractedFeatures.MFCC.coeffs(13), app.extractedFeatures.MFCC.coeffs(1), ...
                          app.extractedFeatures.MFCC.coeffs(2), app.extractedFeatures.MFCC.coeffs(3), ...
                          app.extractedFeatures.MFCC.coeffs(4), app.extractedFeatures.MFCC.coeffs(5), ...
                          app.extractedFeatures.MFCC.coeffs(6), app.extractedFeatures.MFCC.coeffs(7), ...
                          app.extractedFeatures.MFCC.coeffs(8), ...
                          app.extractedFeatures.Spectral.max, app.extractedFeatures.Spectral.mean, ...
                          app.extractedFeatures.Spectral.min, app.extractedFeatures.Spectral.std, ...
                          app.extractedFeatures.MEL.melBands(1), app.extractedFeatures.MEL.melBands(2), ...
                          app.extractedFeatures.MEL.melBands(3), app.extractedFeatures.MEL.melBands(4), ...
                          app.extractedFeatures.MEL.melBands(5), app.extractedFeatures.MEL.melBands(6), ...
                          app.extractedFeatures.MEL.melBands(7), app.extractedFeatures.MEL.melBands(8), ...
                          app.extractedFeatures.MEL.melBands(9), app.extractedFeatures.MEL.melBands(10), ...
                          app.extractedFeatures.MEL.melBands(11), app.extractedFeatures.MEL.melBands(12), ...
                          app.extractedFeatures.MEL.melBands(13), app.extractedFeatures.MEL.melBands(14), ...
                          'VariableNames', {'HRmax', 'HRmean', 'HRmin', 'HRstd', ...
                                            'MFCC_10', 'MFCC_11', 'MFCC_12', 'MFCC_13', 'MFCC_14', ...
                                            'MFCC_2', 'MFCC_3', 'MFCC_4', 'MFCC_5', 'MFCC_6', ...
                                            'MFCC_7', 'MFCC_8', 'MFCC_9', ...
                                            'centroidMax', 'centroidMean', 'centroidMin', 'centroidStd', ...
                                            'Mel_f1', 'Mel_f11', 'Mel_f16', 'Mel_f20', 'Mel_f25', ...
                                            'Mel_f30', 'Mel_f35', 'Mel_f40', 'Mel_f45', 'Mel_f49', ...
                                            'Mel_f54', 'Mel_f59', 'Mel_f6', 'Mel_f64'});
                    case 'HR_MFCC_SpecC_MelSpec_Pitch'
                        requiredFields = {'HR', 'MFCC', 'Spectral','MEL', 'Pitch'};
                        validateFields(app, requiredFields);

                         dataTable = table(app.extractedFeatures.HR.max, app.extractedFeatures.HR.mean, ...
                          app.extractedFeatures.HR.min, app.extractedFeatures.HR.std, ...
                          app.extractedFeatures.MFCC.coeffs(9), app.extractedFeatures.MFCC.coeffs(10), ...
                          app.extractedFeatures.MFCC.coeffs(11), app.extractedFeatures.MFCC.coeffs(12), ...
                          app.extractedFeatures.MFCC.coeffs(13), app.extractedFeatures.MFCC.coeffs(1), ...
                          app.extractedFeatures.MFCC.coeffs(2), app.extractedFeatures.MFCC.coeffs(3), ...
                          app.extractedFeatures.MFCC.coeffs(4), app.extractedFeatures.MFCC.coeffs(5), ...
                          app.extractedFeatures.MFCC.coeffs(6), app.extractedFeatures.MFCC.coeffs(7), ...
                          app.extractedFeatures.MFCC.coeffs(8), ...
                          app.extractedFeatures.Spectral.max, app.extractedFeatures.Spectral.mean, ...
                          app.extractedFeatures.Spectral.min, app.extractedFeatures.Spectral.std, ...
                          app.extractedFeatures.MEL.melBands(1), app.extractedFeatures.MEL.melBands(2), ...
                          app.extractedFeatures.MEL.melBands(3), app.extractedFeatures.MEL.melBands(4), ...
                          app.extractedFeatures.MEL.melBands(5), app.extractedFeatures.MEL.melBands(6), ...
                          app.extractedFeatures.MEL.melBands(7), app.extractedFeatures.MEL.melBands(8), ...
                          app.extractedFeatures.MEL.melBands(9), app.extractedFeatures.MEL.melBands(10), ...
                          app.extractedFeatures.MEL.melBands(11), app.extractedFeatures.MEL.melBands(12), ...
                          app.extractedFeatures.MEL.melBands(13), app.extractedFeatures.MEL.melBands(14), ...
                          app.extractedFeatures.Pitch.Mean,...
                          'VariableNames', {'HRmax', 'HRmean', 'HRmin', 'HRstd', ...
                                            'MFCC_10', 'MFCC_11', 'MFCC_12', 'MFCC_13', 'MFCC_14', ...
                                            'MFCC_2', 'MFCC_3', 'MFCC_4', 'MFCC_5', 'MFCC_6', ...
                                            'MFCC_7', 'MFCC_8', 'MFCC_9', ...
                                            'centroidMax', 'centroidMean', 'centroidMin', 'centroidStd', ...
                                            'Mel_f1', 'Mel_f11', 'Mel_f16', 'Mel_f20', 'Mel_f25', ...
                                            'Mel_f30', 'Mel_f35', 'Mel_f40', 'Mel_f45', 'Mel_f49', ...
                                            'Mel_f54', 'Mel_f59', 'Mel_f6', 'Mel_f64' 'PitchMean'});
                    otherwise
                        uialert(app.MusicClassificactionAppUIFigure, 'Combinación de características no válida.', 'Error');
                        return;
                end
                

                % Realizar la predicción si hay un modelo cargado
                if ~isempty(app.trainedClassifier)
                    % Obtener los nombres de las características requeridas por el modelo
                    predictorNames = app.trainedClassifier.RequiredVariables;
                    disp('Variables Requeridas: ')
                    disp(predictorNames);
                    disp('Variables extraidas: ')
                    disp(dataTable);
                    % Renombrar las columnas de dataTable con los nombres correctos
                    dataTable.Properties.VariableNames = predictorNames;
                    disp('Data table loaded: ')
                    disp(dataTable);
    
                    % Realizar la predicción usando el modelo cargado
                    predictions = app.trainedClassifier.predictFcn(dataTable);
                    
                    % Mostrar las predicciones
                    app.PredictionResultLabel.Text = ['Predicción: ', strjoin(predictions, ', ')];
        
                    % Obtener el tipo de predicción desde el dropdown
                    predictionType = app.PredictionTypeDropDown.Value;
                
                    % Definir las clases según el tipo de predicción
                    if strcmp(predictionType, 'genre')
                        % Clases para géneros
                        genreClasses = {'afrobeats', 'blues', 'classical', 'country', 'dance', 'disco', ...
                                        'hiphop', 'jazz', 'loFi_HipHop', 'metal', 'pop', 'reggae', ...
                                        'reggaeton', 'rock', 'techHouse'};
                        app.ClassNames = genreClasses;  % Guardar las clases en la app
                    elseif strcmp(predictionType, 'emotion')
                        % Clases para emociones
                        emotionClasses = {'ANGER_HIGH', 'ANGER_MODERATE', 'ENERGY NEG HIGH', 'ENERGY NEG MODERATE', ...
                                          'ENERGY POS HIGH', 'ENERGY POS MODERATE', 'FEAR_HIGH', 'FEAR_MODERATE', ...
                                          'HAPPY_HIGH', 'HAPPY_MODERATE', 'SAD_HIGH', 'SAD_MODERATE', 'TENDER_HIGH', ...
                                          'TENDER_MODERATE', 'TENSION NEG HIGH', 'TENSION NEG MODERATE', ...
                                          'TENSION POS HIGH', 'TENSION POS MODERATE', 'VALENCE NEG HIGH', ...
                                          'VALENCE NEG MODERATE', 'VALENCE POS HIGH', 'VALENCE POS MODERATE'};
                        app.ClassNames = emotionClasses;  % Guardar las clases en la app
                    else
                        % Si no se ha seleccionado una opción válida
                        uialert(app.MusicClassificactionAppUIFigure, 'Por favor seleccione un tipo de predicción válido (Géneros o Emociones).', 'Error');
                    end
                    
% 
%                     % Calcular métricas (precisión, recall, F1)
%                     accuracy = sum(diag(cm)) / sum(cm(:));  % Precisión general
%                     recall = diag(cm) ./ sum(cm, 2);        % Recall por clase
%                     precision = diag(cm) ./ sum(cm, 1)';    % Precisión por clase
%                     f1Score = 2 * (precision .* recall) ./ (precision + recall);  % F1 por clase
% 
%                     % Mostrar métricas en la etiqueta de la UI
%                     metricsText = sprintf('Precisión global: %.2f\n', accuracy);
%                     metricsText = [metricsText, sprintf('Recall por clase: %.2f\n', mean(recall))];
%                     metricsText = [metricsText, sprintf('Precisión por clase: %.2f\n', mean(precision))];
%                     metricsText = [metricsText, sprintf('F1 Score promedio: %.2f', mean(f1Score))];
% 
%                     % Asignar el texto a la etiqueta
%                     app.PredictionResultLabel.Text = metricsText;

                else
                    uialert(app.MusicClassificactionAppUIFigure, 'Primero debe cargar un modelo.', 'Error de Predicción');
                end
                
            else
                uialert(app.MusicClassificactionAppUIFigure, 'Las características no han sido extraídas.', 'Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MusicClassificactionAppUIFigure = uifigure('Visible', 'off');
            app.MusicClassificactionAppUIFigure.Name = 'Music Classificaction App';
            app.MusicClassificactionAppUIFigure.Icon = fullfile(pathToMLAPP, 'icons', 'loadSong.jpg');
            app.TabGroup = uitabgroup(app.MusicClassificactionAppUIFigure);
            app.LOADTab = uitab(app.TabGroup);
            app.LOADTab.Title = 'LOAD ';
            app.LOADTab.Tag = 'LOAD SONG';
            app.WaveformAxes = uiaxes(app.LOADTab);
            app.WaveformAxes.YLim = [-1 1];
            colormap(app.WaveformAxes, 'spring')
            app.LoadSongButton = uibutton(app.LOADTab, 'push');
            app.LoadSongButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSongButtonPushed, true);
            app.LoadSongButton.Icon = fullfile(pathToMLAPP, 'icons', 'loadSong.png');
            app.LoadSongButton.Text = 'Load Song';
            app.NameSongLabel = uilabel(app.LOADTab);
            app.NameSongLabel.Tag = 'FilePathLabel';
            app.NameSongLabel.Text = '';
            app.PlaySongButton = uibutton(app.LOADTab, 'push');
            app.PlaySongButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySongButtonPushed, true);
            app.PlaySongButton.Icon = fullfile(pathToMLAPP, 'icons', 'play square.jpg');
            app.PlaySongButton.Text = '';
            app.Button = uibutton(app.LOADTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @PauseSongButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'icons', 'pausesquare.png');
            app.Button.Text = '';
            app.FEATURESTab = uitab(app.TabGroup);
            app.FEATURESTab.Title = 'FEATURES';
            app.FeatureAxes = uiaxes(app.FEATURESTab);
            xlabel(app.FeatureAxes, 'X')
            ylabel(app.FeatureAxes, 'Y')
            zlabel(app.FeatureAxes, 'Z')
            app.PITCHCheckBox = uicheckbox(app.FEATURESTab);
            app.PITCHCheckBox.ValueChangedFcn = createCallbackFcn(app, @PitchCheckBoxValueChanged, true);
            app.PITCHCheckBox.Text = ' PITCH';
            app.MFCCCheckBox = uicheckbox(app.FEATURESTab);
            app.MFCCCheckBox.ValueChangedFcn = createCallbackFcn(app, @MFCCCheckBoxValueChanged, true);
            app.MFCCCheckBox.Text = ' MFCC';
            app.HARMONICRATIOCheckBox = uicheckbox(app.FEATURESTab);
            app.HARMONICRATIOCheckBox.ValueChangedFcn = createCallbackFcn(app, @HRCheckBoxValueChanged, true);
            app.HARMONICRATIOCheckBox.Text = ' HARMONIC RATIO';
            app.MELSPECTROGRAMCheckBox = uicheckbox(app.FEATURESTab);
            app.MELSPECTROGRAMCheckBox.ValueChangedFcn = createCallbackFcn(app, @MelCheckBoxValueChanged, true);
            app.MELSPECTROGRAMCheckBox.Text = ' MEL SPECTROGRAM';
            app.SPECTRALCENTROIDCheckBox = uicheckbox(app.FEATURESTab);
            app.SPECTRALCENTROIDCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpectralCheckBoxValueChanged, true);
            app.SPECTRALCENTROIDCheckBox.Text = ' SPECTRAL CENTROID';
            app.FEATURESSELECTIONLabel = uilabel(app.FEATURESTab);
            app.FEATURESSELECTIONLabel.Text = 'FEATURES SELECTION';
            app.Label = uilabel(app.FEATURESTab);
            app.Label.Text = '';
            app.FeatureDescriptionArea = uitextarea(app.FEATURESTab);
            app.PREDICTIONTab = uitab(app.TabGroup);
            app.PREDICTIONTab.Title = 'PREDICTION';
            app.ConfusionMatrixAxes = uiaxes(app.PREDICTIONTab);
            app.ConfusionMatrixAxes.XTick = [];
            app.ConfusionMatrixAxes.YTick = [];
            app.FeaturesCombinationDropDownLabel = uilabel(app.PREDICTIONTab);
            app.FeaturesCombinationDropDownLabel.Text = 'Features Combination';
            app.FeaturesCombinationDropDown = uidropdown(app.PREDICTIONTab);
            app.FeaturesCombinationDropDown.Items = {'HR', 'HR_MFCC', 'HR_MFCC_SpecC', 'HR_MFCC_SpecC_MelSpec', 'HR_MFCC_SpecC_MelSpec_Pitch'};
            app.FeaturesCombinationDropDown.Value = 'HR';
            app.PredictionTypeDropDownLabel = uilabel(app.PREDICTIONTab);
            app.PredictionTypeDropDownLabel.Text = 'Prediction Type';
            app.PredictionTypeDropDown = uidropdown(app.PREDICTIONTab);
            app.PredictionTypeDropDown.Items = {'genre', 'emotion'};
            app.PredictionTypeDropDown.Value = 'genre';
            app.ModelDropDownLabel = uilabel(app.PREDICTIONTab);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.PREDICTIONTab);
            app.ModelDropDown.Items = {'KNN', 'NN', 'SVM'};
            app.ModelDropDown.Value = 'KNN';
            app.LoadMODELButton = uibutton(app.PREDICTIONTab, 'push');
            app.LoadMODELButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadMODELButton.Text = 'Load MODEL';
            app.PredictButton = uibutton(app.PREDICTIONTab, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.PredictionResultLabel = uilabel(app.PREDICTIONTab);
            app.PredictionResultLabel.Text = '';
            app.LoadedModelLabel = uilabel(app.PREDICTIONTab);
            app.LoadedModelLabel.Text = '';
            app.MetricsTextArea = uilabel(app.PREDICTIONTab);
            app.MetricsTextArea.Text = '';
            % Show the figure after all components are created
            app.MusicClassificactionAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appPredictions

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MusicClassificactionAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MusicClassificactionAppUIFigure)
        end
    end
end